classdef MyCovid19App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SetDefaultButton            matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        Hyperlink                   matlab.ui.control.Hyperlink
        EditField                   matlab.ui.control.EditField
        OptionsButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                 matlab.ui.control.RadioButton
        CumulativeButton            matlab.ui.control.RadioButton
        DatatoplotButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        CasesButton                 matlab.ui.control.RadioButton
        AveragednumberofdaysSlider  matlab.ui.control.Slider
        AveragednumberofdaysSliderLabel  matlab.ui.control.Label
        CountryListBox              matlab.ui.control.ListBox
        CountryLabel                matlab.ui.control.Label
        StateorRegionListBox        matlab.ui.control.ListBox
        StateorRegionLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Dates
        ListOfCountries CovidCountryData   % property validation
        ListOfCountryNames
        NumberOfCountries
        GlobalCumulativeCases
        GlobalCumulativeDeaths
        GlobalDailyCases
        GlobalDailyDeaths
        AverageCumulativeCases    % moving average of cumulative cases
        AverageCumulativeDeaths   % moving average of cumulative deaths
        AverageDailyCases         % moving average of daily cases
        AverageDailyDeaths        % moving average of daily deaths
        TimePoints                % date labels in form of 'mm/dd'
        AverageOfDay
        Title
        CountryToFind
    end
    
    methods (Access = private)
        function PlotData(app)
            app.UIAxes.reset();
            cla(app.UIAxes);
            if app.CasesButton.Value && app.CumulativeButton.Value
                app.ComputeAverageCumulativeCases();
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                set(app.UIAxes, 'FontSize', 14);
                %legend(app.UIAxes, 'Cumulative Cases', 'FontSize', 9, 'Location', 'northwest');
                datetick(app.UIAxes, 'x', 'mm/dd');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Cumulative Number of Cases in ', app.Title], 'FontSize', 16);
            elseif app.CasesButton.Value && app.DailyButton.Value
                app.ComputeAverageDailyCases();
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                set(app.UIAxes, 'FontSize', 14);
                %legend(app.UIAxes, 'Daily Cases', 'FontSize', 9, 'Location', 'northwest');
                datetick(app.UIAxes, 'x', 'mm/dd');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Daily Number of Cases in ', app.Title], 'FontSize', 16);
            elseif app.DeathsButton.Value && app.CumulativeButton.Value
                app.ComputeAverageCumulativeDeaths();
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'r', 'LineWidth', 1.5);
                set(app.UIAxes, 'FontSize', 14);
                %legend(app.UIAxes, 'Cumulative Deaths', 'FontSize', 9, 'Location', 'northwest');
                datetick(app.UIAxes, 'x', 'mm/dd');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Cumulative Number of Deaths in ', app.Title], 'FontSize', 16);
            elseif app.DeathsButton.Value && app.DailyButton.Value
                app.ComputeAverageDailyDeaths();
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'r', 'LineWidth', 1.5);
                set(app.UIAxes, 'FontSize', 14);
                %legend(app.UIAxes, 'Daily Deaths', 'FontSize', 9, 'Location', 'northwest');
                datetick(app.UIAxes, 'x', 'mm/dd');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Daily Number of Deaths in ', app.Title], 'FontSize', 16);
            elseif app.BothButton.Value && app.CumulativeButton.Value
                app.ComputeAverageCumulativeCases();
                app.ComputeAverageCumulativeDeaths();
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ytl = get(app.UIAxes, 'YTick');                       % Get controlling left ticks
                hold(app.UIAxes, 'on');
                % plot 2
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'r', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 1 0 0 ];
                ytr = get(app.UIAxes, 'YTick');                       % Get original right tick values
                ytrv = linspace(0, max(ytr), numel(ytl));             % Create new right tick values matching # left ticks
                newRightTicks = sort([ ytrv ytr(~ismember(ytr,ytrv)) ]);   
                set(app.UIAxes, 'YTick', newRightTicks);              % Set new right ticks
                set(app.UIAxes, 'FontSize', 14);
                datetick(app.UIAxes, 'x', 'mm/dd');
                %legend(app.UIAxes, {'Cumulative Cases','Cumulative Deaths'}, 'FontSize', 9, 'Location', 'northwest');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Cumulative Number of Cases and Deaths in ', app.Title], 'FontSize', 16);
                hold(app.UIAxes, 'off');
            elseif app.BothButton.Value && app.DailyButton.Value
                app.ComputeAverageDailyCases();
                app.ComputeAverageDailyDeaths();
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ytl = get(app.UIAxes, 'YTick');                       % Get controlling left ticks
                hold(app.UIAxes, 'on');
                % plot 2
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'r', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 1 0 0 ];
                ytr = get(app.UIAxes, 'YTick');                       % Get original right tick values
                ytrv = linspace(min(ytr), max(ytr), numel(ytl));      % Create new right tick values matching # left ticks
                newRightTicks = sort([ ytrv ytr(~ismember(ytr,ytrv)) ]);   
                set(app.UIAxes, 'YTick', newRightTicks);              % Set new right ticks
                set(app.UIAxes, 'FontSize', 14);
                datetick(app.UIAxes, 'x', 'mm/dd');
                %legend(app.UIAxes, {'Daily Cases','Daily Deaths'}, 'FontSize', 9, 'Location', 'northwest');
                app.Title = app.MakeTitle();
                title(app.UIAxes, ['Daily Number of Cases and Deaths in ', app.Title], 'FontSize', 16);
                hold(app.UIAxes, 'off');
            end
            grid(app.UIAxes, 'on');
        end
        function ComputeAverageCumulativeCases(app)
            if isequal(app.CountryListBox.Value, 'Global')
                app.AverageCumulativeCases = movmean(app.GlobalCumulativeCases, app.AverageOfDay);
            else
                IndexOfCurrentCountry = findobj(app.ListOfCountries, 'Name', app.CountryListBox.Value).IndexOfCountry;
                if isequal(app.StateorRegionListBox.Value, 'All')
                    app.AverageCumulativeCases = movmean(app.ListOfCountries(IndexOfCurrentCountry).CumulativeCases, ...
                                                         app.AverageOfDay);
                else
                    [~,index] = ismember(app.StateorRegionListBox.Value, app.StateorRegionListBox.Items);
                    app.AverageCumulativeCases = movmean(app.ListOfCountries(IndexOfCurrentCountry).ListOfStates{index-1}.CumulativeCases, ...
                                                         app.AverageOfDay);
                end
            end
        end
        function ComputeAverageCumulativeDeaths(app)
            if isequal(app.CountryListBox.Value, 'Global')
                app.AverageCumulativeDeaths = movmean(app.GlobalCumulativeDeaths, app.AverageOfDay);
            else
                IndexOfCurrentCountry = findobj(app.ListOfCountries, 'Name', app.CountryListBox.Value).IndexOfCountry;
                if isequal(app.StateorRegionListBox.Value, 'All')
                    app.AverageCumulativeDeaths = movmean(app.ListOfCountries(IndexOfCurrentCountry).CumulativeDeaths, ...
                                                          app.AverageOfDay);
                else
                    [~,index] = ismember(app.StateorRegionListBox.Value, app.StateorRegionListBox.Items);
                    app.AverageCumulativeDeaths = movmean(app.ListOfCountries(IndexOfCurrentCountry).ListOfStates{index-1}.CumulativeDeaths, ...
                                                          app.AverageOfDay);
                end
            end
        end
        function ComputeAverageDailyCases(app)
            if isequal(app.CountryListBox.Value, 'Global')
                app.AverageDailyCases = movmean(app.GlobalDailyCases, app.AverageOfDay);
            else
                IndexOfCurrentCountry = findobj(app.ListOfCountries, 'Name', app.CountryListBox.Value).IndexOfCountry;
                if isequal(app.StateorRegionListBox.Value, 'All')
                    app.AverageDailyCases = movmean(app.ListOfCountries(IndexOfCurrentCountry).DailyCases, ...
                                                    app.AverageOfDay);
                else
                    [~,index] = ismember(app.StateorRegionListBox.Value, app.StateorRegionListBox.Items);
                    app.AverageDailyCases = movmean(app.ListOfCountries(IndexOfCurrentCountry).ListOfStates{index-1}.DailyCases, ...
                                                    app.AverageOfDay);
                end
            end
        end
        function ComputeAverageDailyDeaths(app)
            if isequal(app.CountryListBox.Value, 'Global')
                app.AverageDailyDeaths = movmean(app.GlobalDailyDeaths, app.AverageOfDay);
            else
                IndexOfCurrentCountry = findobj(app.ListOfCountries, 'Name', app.CountryListBox.Value).IndexOfCountry;
                if isequal(app.StateorRegionListBox.Value, 'All')
                    app.AverageDailyDeaths = movmean(app.ListOfCountries(IndexOfCurrentCountry).DailyDeaths, ...
                                                     app.AverageOfDay);
                else
                    [~,index] = ismember(app.StateorRegionListBox.Value, app.StateorRegionListBox.Items);
                    app.AverageDailyDeaths = movmean(app.ListOfCountries(IndexOfCurrentCountry).ListOfStates{index-1}.DailyDeaths, ...
                                                     app.AverageOfDay);
                end
            end
        end
        function title = MakeTitle(app)
            if (app.AverageOfDay > 1)
                str1 = ' (';
                str2 = '-day mean)';
                strAverages = [ str1, num2str(app.AverageOfDay), str2 ];
            else
                strAverages = '';
            end
            if isequal(app.StateorRegionListBox.Value, 'All')
                title = [ char(app.CountryListBox.Value), strAverages ];
            else
                title = [ char(app.StateorRegionListBox.Value), strAverages ];
            end                  
        end
        function SetDefault(app)
            % default setting
            app.EditField.FontColor = [ 0.65 0.65 0.65 ];
            app.EditField.BackgroundColor = 'white';
            app.EditField.Placeholder = 'Enter country name...';
            app.CountryListBox.Items = [ {'Global'}; app.ListOfCountryNames ];
            app.StateorRegionListBox.Items = {'All'};
            app.CountryListBox.Value = 'Global';
            scroll(app.CountryListBox, 'top');
            app.StateorRegionListBox.Value = 'All';
            app.CountryListBox.Value = 'Global';
            app.StateorRegionListBox.Value = 'All';
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            app.CasesButton.Value = true;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            app.AverageOfDay = 1;
            app.AveragednumberofdaysSlider.Value = 1;
            app.PlotData();
        end
        function LabelDates(app)
            startDate = datenum(app.Dates{1}, 'mm/dd/yy');
            endDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.TimePoints = linspace(startDate, endDate, length(app.Dates));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.Dates = covid_data(1,3:end);
            app.ListOfCountryNames = unique(covid_data(2:end,1));
            app.CountryListBox.Items = [ {'Global'}; app.ListOfCountryNames ];
            app.NumberOfCountries = length(app.ListOfCountryNames);
            for ii = 1 : app.NumberOfCountries
                app.ListOfCountries(ii) = CovidCountryData(covid_data, app.ListOfCountryNames{ii});
            end
            app.StateorRegionListBox.Items = {'All'};
            app.GlobalCumulativeCases = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeCases = app.GlobalCumulativeCases + app.ListOfCountries(i).CumulativeCases;
            end
            app.GlobalCumulativeDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeDeaths = app.GlobalCumulativeDeaths + app.ListOfCountries(i).CumulativeDeaths;
            end
            app.GlobalDailyCases = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyCases = app.GlobalDailyCases + app.ListOfCountries(i).DailyCases;
            end
            app.GlobalDailyDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyDeaths = app.GlobalDailyDeaths + app.ListOfCountries(i).DailyDeaths;
            end
            app.LabelDates();
            app.AverageOfDay = 1;
            app.EditField.FontColor = [ 0.65 0.65 0.65 ];
            app.EditField.Placeholder = 'Enter country name...';
            app.EditField.FontWeight = 'bold';
            app.ClearButton.Tooltip = 'Clear entered text and change to default setting';
            app.PlotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            CurrentCountry = app.CountryListBox.Value;
            app.StateorRegionListBox.Items = [ {'All'}, ...
                                               findobj(app.ListOfCountries, 'Name', CurrentCountry).ListOfStateNames ];
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.PlotData();
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.PlotData();
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.PlotData();
        end

        % Value changed function: AveragednumberofdaysSlider
        function AveragednumberofdaysSliderValueChanged(app, event)
            % determine which discrete option the current value is closest to
            [~,minIdx] = min(abs(app.AveragednumberofdaysSlider.Value - app.AveragednumberofdaysSlider.MajorTicks(:)));
            % move the slider to that option
            app.AveragednumberofdaysSlider.Value = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % override the selected value if you plan on using it within this function
            app.AverageOfDay = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % plot data
            app.PlotData();
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            app.CountryToFind = event.Value;
            if (app.CountryToFind == "")
                app.SetDefault();
            else
                Countries = [ {'Global'}; app.ListOfCountryNames ];
                idx = startsWith(lower(string(Countries)), lower(app.CountryToFind));
                if (sum(idx) == 0)
                    app.EditField.BackgroundColor = 'red';
                    app.EditField.FontColor = 'black';
                    app.CountryListBox.Items = "No country found.";
                    app.StateorRegionListBox.Items = "";
                    app.UIAxes.reset();
                    cla(app.UIAxes);
                else
                    app.EditField.BackgroundColor = 'yellow';
                    app.EditField.FontColor = '#05014a';
                    FilteredValues = Countries(idx);
                    app.CountryListBox.Items = FilteredValues;
                    app.CountryListBox.Value = app.CountryListBox.Items{1};   % Global
                    app.StateorRegionListBox.Items = [ {'All'}, ...
                                                       findobj(app.ListOfCountries, 'Name', app.CountryListBox.Value).ListOfStateNames ];
                    app.StateorRegionListBox.Value = app.StateorRegionListBox.Items{1};   % All
                    app.PlotData();
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.EditField.Value = '';
            app.SetDefault();
        end

        % Button pushed function: SetDefaultButton
        function SetDefaultButtonPushed(app, event)
             app.SetDefault();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'My COVID-19 App for Data Visualization';
            app.UIFigure.Icon = 'app-icon.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = 'State or Region';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.AveragednumberofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragednumberofdaysSliderLabel.Text = 'Averaged number of days';
            app.AveragednumberofdaysSlider = uislider(app.UIFigure);
            app.AveragednumberofdaysSlider.Limits = [1 15];
            app.AveragednumberofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragednumberofdaysSliderValueChanged, true);
            app.AveragednumberofdaysSlider.MinorTicks = [];
            app.AveragednumberofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://coronavirus.jhu.edu/map.html';
            app.Hyperlink.Text = '(Data source: Coronavirus Resource Center at the Johns Hopkins University)';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'clear-icon.png';
            app.ClearButton.Text = 'Clear';
            app.SetDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultButtonPushed, true);
            app.SetDefaultButton.Icon = 'default-setting-icon.png';
            app.SetDefaultButton.Text = 'Set Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyCovid19App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end